classdef TONE_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        PLAYButton_2   matlab.ui.control.Button
        TONEKnob       matlab.ui.control.DiscreteKnob
        TONEKnobLabel  matlab.ui.control.Label
        STOPButton     matlab.ui.control.Button
        PAUSEButton    matlab.ui.control.Button
        NOISEButton    matlab.ui.control.Button
        LOADButton     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data;
        Nummat;
        Num;
        SamplesPerFrame;
        fileInfo;
        fileReader;
        deviceWriter;
        Md;
        Md1;
        stopFlag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data = load("nummat.mat");
            app.Nummat = app.Data.nummat;
            app.SamplesPerFrame = 100;
            app.fileInfo = audioinfo('qwer.mp3');
            app.fileReader = dsp.AudioFileReader('qwer.mp3','PlayCount',inf);
            app.fileReader.SamplesPerFrame = app.SamplesPerFrame;
            % Системный объект воспроизведения
            app.deviceWriter = audioDeviceWriter('SampleRate', app.fileInfo.SampleRate);
            % Системный объект КИХ-фильтра с измененными коэффициентами
            app.Md = dsp.FIRFilter('Structure',"Direct form symmetric",'NumeratorSource','Input port');
  %         app.Md1 = dsp.LMSFilter(32,0.008);
            app.Num = app.Nummat(str2double(app.TONEKnob.Value),:);
            [h,f] = freqz(app.Num,1,[],app.fileInfo.SampleRate);
            plot(app.UIAxes,f,10*log10(abs(h)),'LineWidth',2);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            [file,path] = uigetfile({'*.mp3';'*.wav';'*.*'});
            addpath(path);
            app.fileInfo = audioinfo(file);
            app.fileReader = dsp.AudioFileReader(file,'PlayCount',inf);
            app.fileReader.SamplesPerFrame = app.SamplesPerFrame;
        end

        % Button pushed function: NOISEButton
        function NOISEButtonPushed(app, event)
            app.stopFlag = false;
            if app.Num == 1
                while ~isDone(app.fileReader)
                    audioData = app.fileReader();
                    audioData = app.Md1(audioData,app.Num);
                    app.deviceWriter(audioData);
                    if (app.stopFlag == true)
                        break;
                    end
                    drawnow();
                end
            else
                while ~isDone(app.fileReader)
                    audioData = app.fileReader();
                    audioData = app.Md(audioData,app.Num);
                    app.deviceWriter(audioData);
                    if app.stopFlag == true
                        break;
                    end
                    drawnow();
                end
            end
        end

        % Value changed function: TONEKnob
        function TONEKnobValueChanged(app, event)
            app.Num = app.Nummat(str2double(app.TONEKnob.Value),:);
            [h,f] = freqz(app.Num,1,[],app.fileInfo.SampleRate);
            plot(app.UIAxes,f,10*log10(abs(h)),'LineWidth',2);
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.stopFlag = true;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stopFlag = true;
            reset(app.fileReader);
            reset(app.deviceWriter);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 20000];
            app.UIAxes.YLim = [-60 10];
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.NOISEButton = uibutton(app.UIFigure, 'push');
            app.NOISEButton.ButtonPushedFcn = createCallbackFcn(app, @NOISEButtonPushed, true);
            app.NOISEButton.Text = 'NOISE';
            app.PAUSEButton = uibutton(app.UIFigure, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TONEKnobLabel = uilabel(app.UIFigure);
            app.TONEKnobLabel.Text = 'TONE';
            app.TONEKnob = uiknob(app.UIFigure, 'discrete');
            app.TONEKnob.Items = {'1', '2', '3', '4', '5', 'Фильтр Винера', 'LMS', 'RLS'};
            app.TONEKnob.ValueChangedFcn = createCallbackFcn(app, @TONEKnobValueChanged, true);
            app.TONEKnob.Value = 'LMS';
            app.PLAYButton_2 = uibutton(app.UIFigure, 'push');
            app.PLAYButton_2.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TONE_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end